classdef DCgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartMenu                       matlab.ui.container.Menu
        uploaddataMenu                  matlab.ui.container.Menu
        uploadmodelMenu                 matlab.ui.container.Menu
        exportmodelMenu                 matlab.ui.container.Menu
        exportdataMenu                  matlab.ui.container.Menu
        DModelSettingsLabel             matlab.ui.control.Label
        addlayerButton                  matlab.ui.control.Button
        GaussiannoiseEditField          matlab.ui.control.NumericEditField
        GaussiannoiseEditFieldLabel     matlab.ui.control.Label
        addnoiseButton                  matlab.ui.control.StateButton
        halfspaceresistivityEditField   matlab.ui.control.NumericEditField
        halfspaceresistivityEditFieldLabel  matlab.ui.control.Label
        distanceMNEditField             matlab.ui.control.NumericEditField
        distanceMNEditFieldLabel        matlab.ui.control.Label
        ElectrodeSettingsLabel          matlab.ui.control.Label
        maxdistanceEditField            matlab.ui.control.NumericEditField
        maxdistanceEditFieldLabel       matlab.ui.control.Label
        mindistanceEditField            matlab.ui.control.NumericEditField
        mindistanceEditFieldLabel       matlab.ui.control.Label
        electrodedistributionDropDown   matlab.ui.control.DropDown
        electrodedistributionLabel      matlab.ui.control.Label
        noofmeasurementsEditField       matlab.ui.control.NumericEditField
        noofmeasurementsEditFieldLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        UITable                         matlab.ui.control.Table
        nooflayersEditField             matlab.ui.control.NumericEditField
        nooflayersEditFieldLabel        matlab.ui.control.Label
        SoundingconfigurationDropDown   matlab.ui.control.DropDown
        SoundingconfigurationDropDownLabel  matlab.ui.control.Label
        simulateButton                  matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dc                              DC
        L_up                            % uploaded L or na or a
        data_up                         % uploaded data
        err_up                          % uploaded errors
    end
    properties (Access = private,Dependent)
        L                              % a or na or L
        data
    end
    
    methods
        
        function L = get.L(app)
            if strcmp(app.electrodedistributionDropDown.Value,'linear')
                switch app.SoundingconfigurationDropDown.Value
                    case 'Dipol-Dipol'
                        L = linspace(app.distanceMNEditField.Value*app.mindistanceEditField.Value,app.distanceMNEditField.Value*app.maxdistanceEditField.Value,app.noofmeasurementsEditField.Value);
                    otherwise
                        L = linspace(app.mindistanceEditField.Value,app.maxdistanceEditField.Value,app.noofmeasurementsEditField.Value);
                end
            else
                switch app.SoundingconfigurationDropDown.Value
                    case 'Dipol-Dipol'
                        L = logspace(log10(app.distanceMNEditField.Value*app.mindistanceEditField.Value),...
                            log10(app.distanceMNEditField.Value*app.maxdistanceEditField.Value),app.noofmeasurementsEditField.Value);
                    otherwise
                        L = logspace(log10(app.mindistanceEditField.Value),log10(app.maxdistanceEditField.Value),app.noofmeasurementsEditField.Value);
                end
            end
        end
        
        function data = get.data(app)
            if app.addnoiseButton.Value
                err = randn(app.noofmeasurementsEditField.Value,1).*app.GaussiannoiseEditField.Value./100.*app.dc.rhoa;
            else
                err = 0;
            end
            data = app.dc.rhoa + err;
        end
    end
    
    methods (Access = private)
        
        function plot_model(app)
            % update model plot
            d = repmat(cumsum(app.dc.d),2,1);
            d = [0; d(:); 200];
            sigma = repmat(app.dc.sigma,2,1);
            sigma = sigma(:);
            plot(app.UIAxes2,1./sigma,-d,'k','Linewidth',2);
        end
        
        function plot_curve(app)
            % delete previous sounding curve
            idx = [];
            for n_kid = 1:numel(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(n_kid).Tag,'sim')
                    idx = [idx n_kid];
                end
            end
            if ~isempty(idx)
                delete(app.UIAxes.Children(idx));
            end
            
            % plot new sounding
            switch app.SoundingconfigurationDropDown.Value
                case 'Schlumberger'
                    if app.addnoiseButton.Value
                        errbar = app.GaussiannoiseEditField.Value/100*app.data;
                        errorbar(app.UIAxes,app.L/2,app.data,errbar,'.-k','Linewidth',1,'Tag','sim','MarkerSize',15,'DisplayName','Simulated')
                    else
                        plot(app.UIAxes,app.L/2,app.data,'.-k','Linewidth',1.5,'Tag','sim','MarkerSize',15,'DisplayName','Simulated')
                    end
                    app.UIAxes.XLim = [app.mindistanceEditField.Value app.maxdistanceEditField.Value]/2;
                otherwise
                    if app.addnoiseButton.Value
                        errbar = app.GaussiannoiseEditField.Value/100*app.data;
                        errorbar(app.UIAxes,app.L,app.data,errbar,'.-k','Linewidth',1,'Tag','sim','MarkerSize',15,'DisplayName','Simulated')
                    else
                        plot(app.UIAxes,app.L,app.data,'.-k','Linewidth',1.5,'Tag','sim','MarkerSize',15,'DisplayName','Simulated')
                    end
                    app.UIAxes.XLim = [app.mindistanceEditField.Value app.maxdistanceEditField.Value];
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.dc  = DC;
            app.dc.sigma    = 1/app.halfspaceresistivityEditField.Value;
            addpath(['.' filesep 'icons']);
            
            app.Image.ImageSource = ['.' filesep 'icons' filesep 'Schlumberger.png'];
            app.plot_model;
        end

        % Button pushed function: simulateButton
        function simulateButtonPushed(app, event)
            switch app.SoundingconfigurationDropDown.Value
                case 'Schlumberger'
                    app.dc.rA = [app.L/2; zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rB = [-app.L/2; zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rM = [zeros(1,app.noofmeasurementsEditField.Value)+app.distanceMNEditField.Value/2;    zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rN = [zeros(1,app.noofmeasurementsEditField.Value)-app.distanceMNEditField.Value/2;    zeros(1,app.noofmeasurementsEditField.Value)];
                case 'Wenner-alpha'
                    app.dc.rA = [1.5*app.L;    zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rB = [-1.5*app.L;   zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rM = [0.5*app.L;    zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rN = [-0.5*app.L;   zeros(1,app.noofmeasurementsEditField.Value)];
                case 'Wenner-beta'
                    app.dc.rA = [-0.5*app.L;   zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rB = [-1.5*app.L;   zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rM = [0.5*app.L;    zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rN = [1.5*app.L;    zeros(1,app.noofmeasurementsEditField.Value)];
                case 'Wenner-gamma'
                    app.dc.rA = [1.5*app.L;    zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rB = [-0.5*app.L;   zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rM = [0.5*app.L;    zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rN = [-1.5*app.L;   zeros(1,app.noofmeasurementsEditField.Value)];
                case 'Dipol-Dipol'
                    app.dc.rA = [-app.distanceMNEditField.Value+app.L/2;   zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rB = [-app.L/2; zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rM = [app.distanceMNEditField.Value+app.L/2;    zeros(1,app.noofmeasurementsEditField.Value)];
                    app.dc.rN = [app.L/2;  zeros(1,app.noofmeasurementsEditField.Value)];
            end
            app.dc.appres;
            
            app.plot_curve;
        end

        % Value changed function: SoundingconfigurationDropDown
        function SoundingconfigurationDropDownValueChanged(app, event)
            value = app.SoundingconfigurationDropDown.Value;
            switch value
                case 'Schlumberger'
                    app.distanceMNEditField.Visible = 'on';
                    app.distanceMNEditField.ValueDisplayFormat = '%11.4g';
                    app.distanceMNEditFieldLabel.Visible = 'on';
                    app.distanceMNEditFieldLabel.Text = 'distance MN:';
                    app.UIAxes.XLabel.String = 'L/2';
                    
                    app.mindistanceEditFieldLabel.Text = 'min. distance:';
                    app.maxdistanceEditFieldLabel.Text = 'max. distance:';
                case 'Dipol-Dipol'
                    app.distanceMNEditField.Visible = 'on';
                    app.distanceMNEditField.ValueDisplayFormat = '%.0f';
                    app.distanceMNEditFieldLabel.Visible = 'on';
                    app.distanceMNEditFieldLabel.Text = 'a distance:';
                    app.UIAxes.XLabel.String = 'na';
                    
                    app.mindistanceEditFieldLabel.Text = 'min. n:';
                    app.maxdistanceEditFieldLabel.Text = 'max. n:';
                otherwise
                    app.distanceMNEditField.Visible = 'off';
                    app.distanceMNEditFieldLabel.Visible = 'off';
                    app.UIAxes.XLabel.String = 'a';
                    
                    app.mindistanceEditFieldLabel.Text = 'min. distance:';
                    app.maxdistanceEditFieldLabel.Text = 'max. distance:';
            end
            app.Image.ImageSource = ['.' filesep 'icons' filesep value '.png'];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if indices(2) == 1
                app.dc.d(indices(1))        = newData;
            else
                app.dc.sigma(indices(1))    = 1/newData;
            end
            app.plot_model;
        end

        % Value changed function: nooflayersEditField
        function nooflayersEditFieldValueChanged(app, event)
            value = app.nooflayersEditField.Value;
            app.UITable.Data    = NaN(value,2);
            app.dc.d            = NaN(1,value);
            app.dc.sigma        = [NaN(1,value) 1/app.halfspaceresistivityEditField.Value];
            app.plot_model;
        end

        % Menu selected function: exportdataMenu
        function exportdataMenuSelected(app, event)
            if ~exist('export','dir')
                mkdir('export');
            end
            
            answer = inputdlg('Enter data output file name:','Data export');
            if ~contains(answer,'.txt')
                answer = [answer{1} '.txt'];
            end
            fid = fopen(fullfile(pwd,'export',answer),'w');
            fprintf(fid,'Configuration:\t%s\n',app.SoundingconfigurationDropDown.Value);
            switch app.SoundingconfigurationDropDown.Value
                case 'Schlumberger'
                    fprintf(fid,'L/2\trho_a\terr\n');
                case 'Dipol-Dipol'
                    fprintf(fid,'distance:\t%i\n',ceil(app.distanceMNEditField.Value));
                    fprintf(fid,'a\trho_a\terr\n');
                otherwise
                    fprintf(fid,'a\trho_a\terr\n');
            end
            if app.addnoiseButton.Value
                A   = [app.L;app.data';app.GaussiannoiseEditField.Value/100*app.data'];
            else
                A   = [app.L;app.data';zeros(1,app.noofmeasurementsEditField.Value)];
            end
            
            fprintf(fid,'%.4e\t%.4e\t%.4e\n',A);
            fclose(fid);
        end

        % Value changed function: GaussiannoiseEditField, addnoiseButton
        function addnoiseButtonValueChanged(app, event)
            app.plot_curve;
        end

        % Menu selected function: exportmodelMenu
        function exportmodelMenuSelected(app, event)
            if ~exist('export','dir')
                mkdir('export');
            end
            
            answer = inputdlg('Enter model output file name:','Model export');
            if ~contains(answer,'.txt')
                answer = [answer '.txt'];
            end
            fid = fopen(fullfile(pwd,'export',answer),'w');
            fprintf(fid,'Halfspace [Ohm*m]:\t%.4e\n',app.halfspaceresistivityEditField.Value);
            fprintf(fid,'d\trho\n');
            A   = [app.dc.d;1./app.dc.sigma(1:end-1)];
            fprintf(fid,'%.4e\t%.4e\n',A);
            fclose(fid);
        end

        % Menu selected function: uploaddataMenu
        function uploaddataMenuSelected(app, event)
            idx = [];
            for n_kid = 1:numel(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(n_kid).Tag,'up')
                    idx = [idx n_kid];
                end
            end
            if ~isempty(idx)
                delete(app.UIAxes.Children(idx));
            end
            
            [file,path] = uigetfile('*.txt');
            
            if file == 0
                return
            end
            
            fid = fopen(fullfile(path,file),'r');
            while ~feof(fid)
                line = fgetl(fid);
                if contains(line,'Configuration')
                    config = sscanf(line,'Configuration:\t%s\n');
                    if ~strcmp(app.SoundingconfigurationDropDown.Value,config)
                        error('Make sure that uploaded and simulated data use the same sounding configuration.')
                    end
                else
                    break
                end
            end
            A = fscanf(fid,'%e\t%e\t%e\n',[3,Inf]);
            fclose(fid);
            
            app.L_up = A(1,:);
            app.data_up = A(2,:);
            app.err_up = A(3,:);
            
            % delete previous sounding curve
            if strcmp(config,'Schlumberger')
                errorbar(app.UIAxes,app.L_up/2,app.data_up,app.err_up,'.-b','Linewidth',1,'Tag','up','MarkerSize',15,'DisplayName','External')
            else
                errorbar(app.UIAxes,app.L_up,app.data_up,app.err_up,'.-b','Linewidth',1,'Tag','up','MarkerSize',15,'DisplayName','External')
            end
            legend(app.UIAxes);
        end

        % Menu selected function: uploadmodelMenu
        function uploadmodelMenuSelected(app, event)
            [file,path] = uigetfile('*.txt');
            
            if file == 0
                return
            end
            
            fid = fopen(fullfile(path,file),'r');
            while ~feof(fid)
                line = fgetl(fid);
                if contains(line,'Halfspace')
                    app.halfspaceresistivityEditField.Value = sscanf(line,'Halfspace [Ohm*m]:\t%e\n');
                else
                    break
                end
            end
            A = fscanf(fid,'%e\t%e\n',[2,Inf]);
            fclose(fid);
            
            app.nooflayersEditField.Value = size(A,2);
            app.UITable.Data = A';
            
            app.dc.d        = A(1,:);
            app.dc.sigma    = [1./A(2,:) 1/app.halfspaceresistivityEditField.Value];
            app.plot_model;
        end

        % Value changed function: halfspaceresistivityEditField
        function halfspaceresistivityEditFieldValueChanged(app, event)
            app.dc.sigma(end) = 1/app.halfspaceresistivityEditField.Value;
        end

        % Button pushed function: addlayerButton
        function addlayerButtonPushed(app, event)
            app.nooflayersEditField.Value = app.nooflayersEditField.Value+1;
            app.UITable.Data = [app.UITable.Data; nan(1,2)];
            app.dc.d        = [app.dc.d NaN];
            app.dc.sigma    = [app.dc.sigma(1:end-1) NaN 1/app.halfspaceresistivityEditField.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartMenu = uimenu(app.UIFigure);
            app.StartMenu.Text = 'Start';
            app.uploaddataMenu = uimenu(app.StartMenu);
            app.uploaddataMenu.MenuSelectedFcn = createCallbackFcn(app, @uploaddataMenuSelected, true);
            app.uploaddataMenu.Text = 'upload data';
            app.uploadmodelMenu = uimenu(app.StartMenu);
            app.uploadmodelMenu.MenuSelectedFcn = createCallbackFcn(app, @uploadmodelMenuSelected, true);
            app.uploadmodelMenu.Text = 'upload model';
            app.exportmodelMenu = uimenu(app.StartMenu);
            app.exportmodelMenu.MenuSelectedFcn = createCallbackFcn(app, @exportmodelMenuSelected, true);
            app.exportmodelMenu.Text = 'export model';
            app.exportdataMenu = uimenu(app.StartMenu);
            app.exportdataMenu.MenuSelectedFcn = createCallbackFcn(app, @exportdataMenuSelected, true);
            app.exportdataMenu.Text = 'export data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sounding Curve')
            xlabel(app.UIAxes, 'L/2 [m]')
            ylabel(app.UIAxes, '\rho_a [\Omega*m]')
            app.UIAxes.XLim = [0 10000];
            app.UIAxes.YLim = [0 1000];
            app.UIAxes.XScale = 'log';
            app.UIAxes.YScale = 'log';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '1D Model')
            xlabel(app.UIAxes2, '\rho [\Omega*m]')
            ylabel(app.UIAxes2, 'Depth [m]')
            app.UIAxes2.XLim = [1 1000];
            app.UIAxes2.YLim = [-200 0];
            app.UIAxes2.XTick = [1 10 100 1000];
            app.UIAxes2.XScale = 'log';
            app.simulateButton = uibutton(app.UIFigure, 'push');
            app.simulateButton.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushed, true);
            app.simulateButton.Text = 'simulate';
            app.SoundingconfigurationDropDownLabel = uilabel(app.UIFigure);
            app.SoundingconfigurationDropDownLabel.Text = 'Sounding configuration:';
            app.SoundingconfigurationDropDown = uidropdown(app.UIFigure);
            app.SoundingconfigurationDropDown.Items = {'Schlumberger', 'Dipol-Dipol', 'Wenner-alpha', 'Wenner-beta', 'Wenner-gamma'};
            app.SoundingconfigurationDropDown.ValueChangedFcn = createCallbackFcn(app, @SoundingconfigurationDropDownValueChanged, true);
            app.SoundingconfigurationDropDown.Value = 'Schlumberger';
            app.nooflayersEditFieldLabel = uilabel(app.UIFigure);
            app.nooflayersEditFieldLabel.Text = 'no. of layers:';
            app.nooflayersEditField = uieditfield(app.UIFigure, 'numeric');
            app.nooflayersEditField.ValueDisplayFormat = '%.0f';
            app.nooflayersEditField.ValueChangedFcn = createCallbackFcn(app, @nooflayersEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'thickness [m]'; 'resistivity [Ohm*m]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Image = uiimage(app.UIFigure);
            app.noofmeasurementsEditFieldLabel = uilabel(app.UIFigure);
            app.noofmeasurementsEditFieldLabel.Text = 'no. of measurements:';
            app.noofmeasurementsEditField = uieditfield(app.UIFigure, 'numeric');
            app.noofmeasurementsEditField.ValueDisplayFormat = '%.0f';
            app.noofmeasurementsEditField.Value = 60;
            app.electrodedistributionLabel = uilabel(app.UIFigure);
            app.electrodedistributionLabel.Text = 'electrode distribution:';
            app.electrodedistributionDropDown = uidropdown(app.UIFigure);
            app.electrodedistributionDropDown.Items = {'logarithmic', 'linear'};
            app.electrodedistributionDropDown.Value = 'logarithmic';
            app.mindistanceEditFieldLabel = uilabel(app.UIFigure);
            app.mindistanceEditFieldLabel.Text = 'min. distance:';
            app.mindistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.mindistanceEditField.Limits = [1 Inf];
            app.mindistanceEditField.Value = 1;
            app.maxdistanceEditFieldLabel = uilabel(app.UIFigure);
            app.maxdistanceEditFieldLabel.Text = 'max. distance:';
            app.maxdistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxdistanceEditField.Limits = [1 Inf];
            app.maxdistanceEditField.Value = 10000;
            app.ElectrodeSettingsLabel = uilabel(app.UIFigure);
            app.ElectrodeSettingsLabel.Text = 'Electrode Settings:';
            app.distanceMNEditFieldLabel = uilabel(app.UIFigure);
            app.distanceMNEditFieldLabel.Text = 'distance MN:';
            app.distanceMNEditField = uieditfield(app.UIFigure, 'numeric');
            app.distanceMNEditField.Limits = [0 Inf];
            app.distanceMNEditField.Value = 0.9;
            app.halfspaceresistivityEditFieldLabel = uilabel(app.UIFigure);
            app.halfspaceresistivityEditFieldLabel.Text = 'halfspace resistivity:';
            app.halfspaceresistivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.halfspaceresistivityEditField.ValueChangedFcn = createCallbackFcn(app, @halfspaceresistivityEditFieldValueChanged, true);
            app.halfspaceresistivityEditField.Value = 500;
            app.addnoiseButton = uibutton(app.UIFigure, 'state');
            app.addnoiseButton.ValueChangedFcn = createCallbackFcn(app, @addnoiseButtonValueChanged, true);
            app.addnoiseButton.Text = 'add noise';
            app.GaussiannoiseEditFieldLabel = uilabel(app.UIFigure);
            app.GaussiannoiseEditFieldLabel.Text = 'Gaussian noise %';
            app.GaussiannoiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.GaussiannoiseEditField.ValueChangedFcn = createCallbackFcn(app, @addnoiseButtonValueChanged, true);
            app.GaussiannoiseEditField.Value = 5;
            app.addlayerButton = uibutton(app.UIFigure, 'push');
            app.addlayerButton.ButtonPushedFcn = createCallbackFcn(app, @addlayerButtonPushed, true);
            app.addlayerButton.Text = 'add layer';
            app.DModelSettingsLabel = uilabel(app.UIFigure);
            app.DModelSettingsLabel.Text = '1D Model Settings:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DCgui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end